classdef drum1identification < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure         matlab.ui.Figure
        InstrumentLabel  matlab.ui.control.Label
        EditField3       matlab.ui.control.EditField
        ResetButton      matlab.ui.control.Button
        AnalyzeButton    matlab.ui.control.Button
        RecordButton     matlab.ui.control.Button
        UIAxes2          matlab.ui.control.UIAxes
        UIAxes           matlab.ui.control.UIAxes
    end


    properties (Access = private)

        fs % Description
        nob % Description
        noc % Description
        audio % Description
        y % Description
        t % Description
        nfft % Description
        f % Description
        ymax % Description
        f1 % Description
        f2 % Description
        xymax % Description
        xmax % Description
        val % Description
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: RecordButton
        function RecordButtonPushed(app, event)
            app.EditField3.Value= '-';
            plot(app.UIAxes2,0,0);
            plot(app.UIAxes,0,0);

            app.RecordButton.BackgroundColor='g';

            app.fs=48000;
            app.nob=16;
            app.noc=2;
            app.audio=audiorecorder(app.fs,app.nob,app.noc);
            recordblocking(app.audio,8); % GINAWA KO NG 10 SECONDS
            app.y=getaudiodata(app.audio);

            app.RecordButton.BackgroundColor='w';


        end

        % Button pushed function: AnalyzeButton
        function AnalyzeButtonPushed(app, event)
            sound(app.y,app.fs);

            %app.fs = medfilt1(app.fs,5);
            plot(app.UIAxes,app.y);

            app.nfft=2^nextpow2(app.fs);
            app.f=linspace(0,app.fs,app.nfft);
            app.y=abs(fft(app.y,app.nfft));

            app.ymax=max(app.y(2:app.nfft/2));
            app.f1=find(app.y==app.ymax);
            app.f2=min(app.f1);
            app.xymax=app.f(app.f2);
            app.xmax=min(app.xymax);

            plot(app.UIAxes2,app.f(2:app.nfft/2),app.y(2:app.nfft/2),'r',app.xmax,app.ymax,'*b');

            final_instrument = "";
            freq_amp = [app.f(app.nfft/2);app.y(app.nfft/2)];
            [mm,nn] = size(freq_amp);
            for n = 1:nn
                freq = freq_amp(1,n);
                amplitude = freq_amp(2,n);
                
                sensitivity_percentage = 0.05;

                amp_thresh = [433, 300, 525, 142, 677, 376, 701, 68, 75, 57, 71, 59, 171];
                assigned_freq = [4376, 3560, 4625, 4397, 4343, 4370, 3732, 4340, 4303, 4163, 3956, 4440, 3875];
                instrument_name = {'Big Crash', 'Ride', 'Open Hi-Hat', 'Close Hi-Hat', 'Small Crash', 'Splash', 'Ride Bell', 'Floor Tom', 'High Tom', 'Mid Tom', 'Low Tom', 'Kick', 'Snare'};

                for n=1:length(amp_thresh)
                    if amplitude > amp_thresh(n) && freq > (assigned_freq(n)*(1-sensitivity_percentage)) && freq < (assigned_freq(n)*(1+sensitivity_percentage)) && ~contains(final_instrument,instrument_name(n))
                        final_instrument = final_instrument + '| '+ instrument_name{n} + ' ';
                        break
                    end
                end

%                 assigned_freq = 4630
%                 if amplitude > 500 && freq>(assigned_freq) && freq<&& ~contains(final_instrument,'Open Hi-Hat')
%                     final_instrument = final_instrument + '| Open Hi-Hat | ';
% 
%                 elseif amplitude > 500 && freq>4395 && freq<4405 && ~contains(final_instrument,'Close Hi-Hat')
%                     final_instrument = final_instrument + '| Close Hi-Hat | ';
% 
%                 elseif amplitude > 100 && freq>4420 && freq<4430 && ~contains(final_instrument,'Big Crash')
%                     final_instrument = final_instrument + '| Big Crash | ';
% 
%                 elseif amplitude > 500 && freq>3992 && freq<4072 && ~contains(final_instrument,'Small Crash')
%                     final_instrument = final_instrument + '| Small Crash | ';
% 
%                 elseif amplitude > 500 && freq>4458 && freq<4463 && ~contains(final_instrument,'Splash')
%                     final_instrument = final_instrument + '| Splash | ';
% 
%                 elseif amplitude > 100 && freq>3550 && freq<3570 && ~contains(final_instrument,'Ride')
%                     final_instrument = final_instrument + '| Ride | ';
% 
%                 elseif amplitude > 500 && freq>3693 && freq<3767 && ~contains(final_instrument,'Ride Bell')
%                     final_instrument = final_instrument + '| Ride Bell | ';
% 
%                 elseif amplitude > 500 && freq>4340 && freq<4350 && ~contains(final_instrument,'Floor Tom')
%                     final_instrument = final_instrument + '| Floor Tom | ';
% 
%                 elseif amplitude > 500 && freq>4300 && freq<4320 && ~contains(final_instrument,'High Tom')
%                     final_instrument = final_instrument + '| High Tom | ';
% 
%                 elseif amplitude > 500 && freq>4160 && freq<4168 && ~contains(final_instrument,'Mid Tom')
%                     final_instrument = final_instrument + '| Mid Tom | ';
% 
%                 elseif amplitude > 500 && freq>4390 && freq<4393 && ~contains(final_instrument,'Low Tom')
%                     final_instrument = final_instrument + '| Low Tom | ';
% 
%                 elseif amplitude > 500 && freq>4431 && freq<4455 && ~contains(final_instrument,'Kick')
%                     final_instrument = final_instrument + '| Kick | ';
% 
%                 elseif amplitude > 500 && freq>3870 && freq<3879 && ~contains(final_instrument,'Snare')
%                     final_instrument = final_instrument + '| Snare | ';
% 
%                 end
            end

            plot(app.UIAxes2,app.f(1:app.nfft/2),app.y(1:app.nfft/2),'r',app.xmax,app.ymax,'*b');
            app.EditField3.Value = final_instrument;
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)

            app.EditField3.Value= '-';
            plot(app.UIAxes2,0,0);
            plot(app.UIAxes,0,0);
            clear sound;
            close all;

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Time Domain')
            xlabel(app.UIAxes, 'Time')
            ylabel(app.UIAxes, 'Amplitude')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'Frequency Domain')
            xlabel(app.UIAxes2, 'Frequency')
            ylabel(app.UIAxes2, 'Amplitude')
            zlabel(app.UIAxes2, 'Z')
            app.RecordButton = uibutton(app.UIFigure, 'push');
            app.RecordButton.ButtonPushedFcn = createCallbackFcn(app, @RecordButtonPushed, true);
            app.RecordButton.Text = 'Record';
            app.AnalyzeButton = uibutton(app.UIFigure, 'push');
            app.AnalyzeButton.ButtonPushedFcn = createCallbackFcn(app, @AnalyzeButtonPushed, true);
            app.AnalyzeButton.Text = 'Analyze';
            app.ResetButton = uibutton(app.UIFigure, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            app.EditField3 = uieditfield(app.UIFigure, 'text');
            app.InstrumentLabel = uilabel(app.UIFigure);
            app.InstrumentLabel.Text = 'Instrument';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = drum1identification

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end